classdef Panel_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PaneldecontrolTab              matlab.ui.container.Tab
        HomeButton                     matlab.ui.control.Button
        CadenaabiertaPanel             matlab.ui.container.Panel
        Slider                         matlab.ui.control.Slider
        Slider2                        matlab.ui.control.Slider
        Slider3                        matlab.ui.control.Slider
        Lamp_2                         matlab.ui.control.Lamp
        Lamp2                          matlab.ui.control.Lamp
        Lamp3                          matlab.ui.control.Lamp
        SteppersSwitchLabel            matlab.ui.control.Label
        SteppersSwitch                 matlab.ui.control.Switch
        CadenacerradaPanel             matlab.ui.container.Panel
        q1mmLabel                      matlab.ui.control.Label
        Xi_2                           matlab.ui.control.Spinner
        q2degLabel                     matlab.ui.control.Label
        Yi_2                           matlab.ui.control.Spinner
        q3degLabel                     matlab.ui.control.Label
        Zi_2                           matlab.ui.control.Spinner
        MoverRobot                     matlab.ui.control.Button
        Lamp_3                         matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        ComunicacinPanel               matlab.ui.container.Panel
        PuertoDropDownLabel            matlab.ui.control.Label
        Puerto                         matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        Baudrate                       matlab.ui.control.DropDown
        BotonGrafico                   matlab.ui.control.Button
        CinemticaTab                   matlab.ui.container.Tab
        CinemticadirectaPanel          matlab.ui.container.Panel
        CoordenadasarticularesPanel    matlab.ui.container.Panel
        Q1Label                        matlab.ui.control.Label
        Q1d                            matlab.ui.control.Spinner
        Q2Label                        matlab.ui.control.Label
        Q2d                            matlab.ui.control.Spinner
        Q3Label                        matlab.ui.control.Label
        Q3d                            matlab.ui.control.Spinner
        CoordenadascartesianasPanel    matlab.ui.container.Panel
        ZLabel                         matlab.ui.control.Label
        Zd                             matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        Yd                             matlab.ui.control.NumericEditField
        XLabel                         matlab.ui.control.Label
        Xd                             matlab.ui.control.NumericEditField
        Lamp_D                         matlab.ui.control.Lamp
        CalcularD                      matlab.ui.control.Button
        CinemticainversaPanel          matlab.ui.container.Panel
        CoordenadascartesianasPanel_2  matlab.ui.container.Panel
        YLabel_2                       matlab.ui.control.Label
        Yi_3                           matlab.ui.control.Spinner
        ZLabel_2                       matlab.ui.control.Label
        Zi_3                           matlab.ui.control.Spinner
        XLabel_2                       matlab.ui.control.Label
        Xi_3                           matlab.ui.control.Spinner
        Lamp_4                         matlab.ui.control.Lamp
        CoordenadasarticularesPanel_2  matlab.ui.container.Panel
        Q2Label_2                      matlab.ui.control.Label
        Q2i                            matlab.ui.control.NumericEditField
        Q3Label_2                      matlab.ui.control.Label
        Q3i                            matlab.ui.control.NumericEditField
        Q1Label_2                      matlab.ui.control.Label
        Q1i                            matlab.ui.control.NumericEditField
        CalcularI                      matlab.ui.control.Button
    end

    methods (Access = public)
    

        function mandarComando(app,str)
            global arduino;
            fprintf(arduino,'%s\n',str);
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.Puerto.Items=seriallist;
         app.Baudrate.Items={'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200'};
         app.Baudrate.Value = {'115200'};
        end

        % Button pushed function: CalcularD
        function CalcularDButtonPushed(app, event)
            Coord=[0 0 0];
            [Coord(1), Coord(2), Coord(3)]=directa(app.Q1d.Value,app.Q2d.Value,app.Q3d.Value);
            if constrains(Coord)==1
               app.Lamp_D.Color='Green';
               app.Xd.Value=Coord(1);
               app.Yd.Value=Coord(2);
               app.Zd.Value=Coord(3);
            else  
               app.Lamp_D.Color='Red';
            end
        end

        % Button pushed function: MoverRobot
        function MoverRobotPushed(app, event)
            COM=app.Puerto.Value;
            app.EditField.Value=COM;
        end

        % Button pushed function: BotonGrafico
        function BotonGraficoButtonPushed(app, event)
            rotate3d(app.UIAxes,'on');
            [x2,y2,z2,x1,y1,z1] = directa(app.Q1d.Value,app.Q2d.Value,app.Q3d.Value);
            plot3(app.UIAxes,[x1 x1 x2] ,[0 y1 y2] ,[0 z1 z2],['r']);
        end

        % Button pushed function: CalcularI
        function CalcularIPushed(app, event)
            x=[app.Xi.Value app.Yi.Value app.Zi.Value];
            if constrains(x)==1
               app.Lamp.Color='Green';
               [app.Q1i.Value,app.Q2i.Value,app.Q3i.Value] = inversa(app.Xi.Value, app.Yi.Value, app.Zi.Value);
            else  
               app.Lamp.Color='Red';
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            mandarComando(app,"J1");
            app.HomeButton.color='Green';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control';
            app.HomeButton = uibutton(app.PaneldecontrolTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.CadenaabiertaPanel = uipanel(app.PaneldecontrolTab);
            app.CadenaabiertaPanel.Title = 'Cadena abierta';
            app.Slider = uislider(app.CadenaabiertaPanel);
            app.Slider.Limits = [-33 33];
            app.Slider2 = uislider(app.CadenaabiertaPanel);
            app.Slider2.Limits = [-33 33];
            app.Slider3 = uislider(app.CadenaabiertaPanel);
            app.Slider3.Limits = [-50 50];
            app.Lamp_2 = uilamp(app.CadenaabiertaPanel);
            app.Lamp2 = uilamp(app.CadenaabiertaPanel);
            app.Lamp3 = uilamp(app.CadenaabiertaPanel);
            app.SteppersSwitchLabel = uilabel(app.PaneldecontrolTab);
            app.SteppersSwitchLabel.Text = 'Steppers';
            app.SteppersSwitch = uiswitch(app.PaneldecontrolTab, 'slider');
            app.CadenacerradaPanel = uipanel(app.PaneldecontrolTab);
            app.CadenacerradaPanel.Title = 'Cadena cerrada';
            app.q1mmLabel = uilabel(app.CadenacerradaPanel);
            app.q1mmLabel.Text = 'q1 (mm)';
            app.Xi_2 = uispinner(app.CadenacerradaPanel);
            app.q2degLabel = uilabel(app.CadenacerradaPanel);
            app.q2degLabel.Text = 'q2 (deg)';
            app.Yi_2 = uispinner(app.CadenacerradaPanel);
            app.q3degLabel = uilabel(app.CadenacerradaPanel);
            app.q3degLabel.Text = 'q3 (deg)';
            app.Zi_2 = uispinner(app.CadenacerradaPanel);
            app.MoverRobot = uibutton(app.CadenacerradaPanel, 'push');
            app.MoverRobot.ButtonPushedFcn = createCallbackFcn(app, @MoverRobotPushed, true);
            app.MoverRobot.Text = 'Enviar';
            app.Lamp_3 = uilamp(app.CadenacerradaPanel);
            app.UIAxes = uiaxes(app.PaneldecontrolTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-400 400];
            app.UIAxes.YLim = [-400 400];
            app.UIAxes.ZLim = [0 400];
            app.ComunicacinPanel = uipanel(app.PaneldecontrolTab);
            app.ComunicacinPanel.Title = 'Comunicación';
            app.PuertoDropDownLabel = uilabel(app.ComunicacinPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.ComunicacinPanel);
            app.Puerto.Items = {};
            app.Puerto.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.ComunicacinPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.ComunicacinPanel);
            app.Baudrate.Items = {};
            app.Baudrate.Value = {};
            app.BotonGrafico = uibutton(app.PaneldecontrolTab, 'push');
            app.BotonGrafico.ButtonPushedFcn = createCallbackFcn(app, @BotonGraficoButtonPushed, true);
            app.BotonGrafico.Text = 'Recalcular';
            app.CinemticaTab = uitab(app.TabGroup);
            app.CinemticaTab.Title = 'Cinemática';
            app.CinemticadirectaPanel = uipanel(app.CinemticaTab);
            app.CinemticadirectaPanel.Title = 'Cinemática directa';
            app.CoordenadasarticularesPanel = uipanel(app.CinemticadirectaPanel);
            app.CoordenadasarticularesPanel.Title = 'Coordenadas articulares';
            app.Q1Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q1Label.Text = 'Q1';
            app.Q1d = uispinner(app.CoordenadasarticularesPanel);
            app.Q2Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q2Label.Text = 'Q2';
            app.Q2d = uispinner(app.CoordenadasarticularesPanel);
            app.Q2d.Value = 1.5;
            app.Q3Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q3Label.Text = 'Q3';
            app.Q3d = uispinner(app.CoordenadasarticularesPanel);
            app.Q3d.Value = -0.29;
            app.CoordenadascartesianasPanel = uipanel(app.CinemticadirectaPanel);
            app.CoordenadascartesianasPanel.Title = 'Coordenadas cartesianas';
            app.ZLabel = uilabel(app.CoordenadascartesianasPanel);
            app.ZLabel.Text = 'Z';
            app.Zd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Zd.Editable = 'off';
            app.YLabel = uilabel(app.CoordenadascartesianasPanel);
            app.YLabel.Text = 'Y';
            app.Yd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Yd.Editable = 'off';
            app.XLabel = uilabel(app.CoordenadascartesianasPanel);
            app.XLabel.Text = 'X';
            app.Xd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Xd.Editable = 'off';
            app.Lamp_D = uilamp(app.CinemticadirectaPanel);
            app.CalcularD = uibutton(app.CinemticadirectaPanel, 'push');
            app.CalcularD.ButtonPushedFcn = createCallbackFcn(app, @CalcularDButtonPushed, true);
            app.CalcularD.Text = 'Calcular';
            app.CinemticainversaPanel = uipanel(app.CinemticaTab);
            app.CinemticainversaPanel.Title = 'Cinemática inversa';
            app.CoordenadascartesianasPanel_2 = uipanel(app.CinemticainversaPanel);
            app.CoordenadascartesianasPanel_2.Title = 'Coordenadas cartesianas';
            app.YLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.YLabel_2.Text = 'Y';
            app.Yi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.ZLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.ZLabel_2.Text = 'Z';
            app.Zi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.XLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.XLabel_2.Text = 'X';
            app.Xi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.Lamp_4 = uilamp(app.CoordenadascartesianasPanel_2);
            app.CoordenadasarticularesPanel_2 = uipanel(app.CinemticainversaPanel);
            app.CoordenadasarticularesPanel_2.Title = 'Coordenadas articulares';
            app.Q2Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q2Label_2.Text = 'Q2';
            app.Q2i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q2i.Editable = 'off';
            app.Q3Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q3Label_2.Text = 'Q3';
            app.Q3i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q3i.Editable = 'off';
            app.Q1Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q1Label_2.Text = 'Q1';
            app.Q1i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q1i.Editable = 'off';
            app.CalcularI = uibutton(app.CinemticainversaPanel, 'push');
            app.CalcularI.ButtonPushedFcn = createCallbackFcn(app, @CalcularIPushed, true);
            app.CalcularI.Text = 'Calcular';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Panel_Control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end